classdef fnafv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RDoorSwitch         matlab.ui.control.ToggleSwitch
        RDoorSwitchLabel    matlab.ui.control.Label
        LDoorSwitch         matlab.ui.control.ToggleSwitch
        LDoorSwitchLabel    matlab.ui.control.Label
        LightSwitch_2       matlab.ui.control.Switch
        LightSwitch_2Label  matlab.ui.control.Label
        LightSwitch         matlab.ui.control.Switch
        LightSwitchLabel    matlab.ui.control.Label
        CameraOnButton      matlab.ui.control.Button
        YouLabel            matlab.ui.control.Label
        CAM4BButton         matlab.ui.control.Button
        CAM4AButton         matlab.ui.control.Button
        CAM2BButton         matlab.ui.control.Button
        CAM2AButton         matlab.ui.control.Button
        CAM3Button          matlab.ui.control.Button
        CAM6Button          matlab.ui.control.Button
        CAM7Button          matlab.ui.control.Button
        CAM5Button          matlab.ui.control.Button
        CAM1CButton         matlab.ui.control.Button
        CAM1BButton         matlab.ui.control.Button
        CAM1AButton         matlab.ui.control.Button
        ShowStagePanel      matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        Label               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PressSpacetostartButtonPushed2(app, event)
            
        end

        % Button pushed function: CAM1AButton
        function CAMC1ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Show Stage';
        end

        % Button pushed function: CAM1BButton
        function CAM1BButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Dinning Area';
        end

        % Callback function
        function ShowStagePanelButtonDown(app, event)
        
                
        end

        % Button pushed function: CAM1CButton
        function CAM1CButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Pirate Cove';
        end

        % Button pushed function: CAM2AButton
        function CAM2AButtonPushed(app, event)
            app.ShowStagePanel.Title = 'West Hall';
        end

        % Button pushed function: CAM2BButton
        function CAM2BButtonPushed(app, event)
            app.ShowStagePanel.Title = 'West Hall Corner';
        end

        % Button pushed function: CAM3Button
        function CAM3ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Supply Closet';
        end

        % Button pushed function: CAM4AButton
        function CAM4AButtonPushed(app, event)
            app.ShowStagePanel.Title = 'East Hall';
        end

        % Button pushed function: CAM4BButton
        function CAM4BButtonPushed(app, event)
            app.ShowStagePanel.Title = 'East Hall Corner';
        end

        % Button pushed function: CAM5Button
        function CAM5ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Backstage';
        end

        % Button pushed function: CAM6Button
        function CAM6ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Kitchen ( - - Video disabled. Audio only - -)';
        end

        % Button pushed function: CAM7Button
        function CAM7ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Restroom';
        end

        % Button pushed function: CameraOnButton
        function CameraOnButtonPushed(app, event)
            switch app.CameraOnButton.Text
                case 'Camera On'
                    app.CameraOnButton.Text = 'Camera Off';
                    app.CAM1AButton.Visible = 'on';
                    app.CAM1BButton.Visible = 'on';
                    app.CAM2AButton.Visible = 'on';
                    app.CAM1CButton.Visible = 'on';
                    app.CAM2BButton.Visible = 'on';
                    app.CAM3Button.Visible = 'on';
                    app.CAM4AButton.Visible = 'on';
                    app.CAM4BButton.Visible = 'on';
                    app.CAM5Button.Visible = 'on';
                    app.CAM6Button.Visible = 'on';
                    app.CAM7Button.Visible = 'on';
                    app.ShowStagePanel.Visible = 'on';
                    app.YouLabel.Visible = 'on';
                case 'Camera Off'
                    app.CameraOnButton.Text = 'Camera On';
                    app.CAM1AButton.Visible = 'off';
                    app.CAM1BButton.Visible = 'off';
                    app.CAM1CButton.Visible = 'off';
                    app.CAM2AButton.Visible = 'off';
                    app.CAM2BButton.Visible = 'off';
                    app.CAM3Button.Visible = 'off';
                    app.CAM4AButton.Visible = 'off';
                    app.CAM4BButton.Visible = 'off';
                    app.CAM5Button.Visible = 'off';
                    app.CAM6Button.Visible = 'off';
                    app.CAM7Button.Visible = 'off';
                    app.YouLabel.Visible = 'off';
                    app.ShowStagePanel.Visible = 'off';
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Load the images of the rooms to the app
            room1 = imread('Show Stage Full.png');
            room1Freddy = imread('Show Stage Freddy Only.png');
            room1NoBonnie = imread('Show Stage No Bonnie.png');
            room1NoChica = imread('Show Stage No Chica.png');
            room1Empty = imread('Show Stage Empty.png');

            room2 = imread('Dinning Hall.png');
            room2Bonnie = imread('Dinning Hall Bonnie.png');
            room2Chica = imread('Dinning Hall Chica.png');
            room2Freddy = imread('Dinning Hall Freddy.png');

            room3 = imread('Pirate Cove.png');
            room3Stage1 = imread('Pirate Cove Foxy Stage 1.png');
            room3Stage2 = imread('Pirate Cove Foxy Stage 2.png');
            room3Stage3 = imread('Pirate Cove Foxy Stage 3.png');

            room4 = imread('West Hall.png');
            room4Bonnie = imread('West Hall Bonnie.png');

            room5 = imread('West Hall Corner.png');
            room5Bonnie = imread('West Hall Corner Bonnie.png');

            room6 = imread('Supply Closet.png');
            room6Bonnie = imread('Supply Closet Bonnie.png');

            room7 = imread('East Hall.png');
            room7Chica = imread('East Hall Chica.png');
            room7Freddy = imread('East Hall Freddy.png');

            room8 = imread('East Hall Corner .png');
            room8Chica = imread('East Hall Corner Chica.png');
            room8Freddy = imread('East Hall Corner Freddy.png');

            room9 = imread('Backstage.png');
            room9Bonnie = imread('Backstage Bonnie.png');

            room11 = imread('Restroom.png');
            room11Chica = imread('Restroom Chica.png');
            room11Freddy = imread('Restroom Freddy.png');
            
            % Show the image
            imshow(room11Chica, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ShowStagePanel = uipanel(app.UIFigure);
            app.ShowStagePanel.Title = 'Show Stage';
            app.UIAxes = uiaxes(app.ShowStagePanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CAM1AButton = uibutton(app.UIFigure, 'push');
            app.CAM1AButton.ButtonPushedFcn = createCallbackFcn(app, @CAMC1ButtonPushed, true);
            app.CAM1AButton.Text = 'CAM 1A';
            app.CAM1BButton = uibutton(app.UIFigure, 'push');
            app.CAM1BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM1BButtonPushed, true);
            app.CAM1BButton.Text = 'CAM 1B';
            app.CAM1CButton = uibutton(app.UIFigure, 'push');
            app.CAM1CButton.ButtonPushedFcn = createCallbackFcn(app, @CAM1CButtonPushed, true);
            app.CAM1CButton.Text = 'CAM 1C';
            app.CAM5Button = uibutton(app.UIFigure, 'push');
            app.CAM5Button.ButtonPushedFcn = createCallbackFcn(app, @CAM5ButtonPushed, true);
            app.CAM5Button.Text = 'CAM 5';
            app.CAM7Button = uibutton(app.UIFigure, 'push');
            app.CAM7Button.ButtonPushedFcn = createCallbackFcn(app, @CAM7ButtonPushed, true);
            app.CAM7Button.Text = 'CAM 7';
            app.CAM6Button = uibutton(app.UIFigure, 'push');
            app.CAM6Button.ButtonPushedFcn = createCallbackFcn(app, @CAM6ButtonPushed, true);
            app.CAM6Button.Text = 'CAM 6';
            app.CAM3Button = uibutton(app.UIFigure, 'push');
            app.CAM3Button.ButtonPushedFcn = createCallbackFcn(app, @CAM3ButtonPushed, true);
            app.CAM3Button.Text = 'CAM 3';
            app.CAM2AButton = uibutton(app.UIFigure, 'push');
            app.CAM2AButton.ButtonPushedFcn = createCallbackFcn(app, @CAM2AButtonPushed, true);
            app.CAM2AButton.Text = 'CAM 2A';
            app.CAM2BButton = uibutton(app.UIFigure, 'push');
            app.CAM2BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM2BButtonPushed, true);
            app.CAM2BButton.Text = 'CAM 2B';
            app.CAM4AButton = uibutton(app.UIFigure, 'push');
            app.CAM4AButton.ButtonPushedFcn = createCallbackFcn(app, @CAM4AButtonPushed, true);
            app.CAM4AButton.Text = 'CAM 4A';
            app.CAM4BButton = uibutton(app.UIFigure, 'push');
            app.CAM4BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM4BButtonPushed, true);
            app.CAM4BButton.Text = 'CAM 4B';
            app.YouLabel = uilabel(app.UIFigure);
            app.YouLabel.Text = 'You';
            app.CameraOnButton = uibutton(app.UIFigure, 'push');
            app.CameraOnButton.ButtonPushedFcn = createCallbackFcn(app, @CameraOnButtonPushed, true);
            app.CameraOnButton.Text = 'Camera On';
            app.LightSwitchLabel = uilabel(app.UIFigure);
            app.LightSwitchLabel.Text = 'Light';
            app.LightSwitch = uiswitch(app.UIFigure, 'slider');
            app.LightSwitch_2Label = uilabel(app.UIFigure);
            app.LightSwitch_2Label.Text = 'Light';
            app.LightSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.LDoorSwitchLabel = uilabel(app.UIFigure);
            app.LDoorSwitchLabel.Text = 'L Door';
            app.LDoorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RDoorSwitchLabel = uilabel(app.UIFigure);
            app.RDoorSwitchLabel.Text = 'R Door';
            app.RDoorSwitch = uiswitch(app.UIFigure, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnafv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end