classdef fnafv5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        RDoorSwitch        matlab.ui.control.ToggleSwitch
        RDoorSwitchLabel   matlab.ui.control.Label
        LDoorSwitch        matlab.ui.control.ToggleSwitch
        LDoorSwitchLabel   matlab.ui.control.Label
        RLightSwitch       matlab.ui.control.Switch
        RLightSwitchLabel  matlab.ui.control.Label
        LLightSwitch       matlab.ui.control.Switch
        LLightSwitchLabel  matlab.ui.control.Label
        CameraOnButton     matlab.ui.control.Button
        YouLabel           matlab.ui.control.Label
        CAM4BButton        matlab.ui.control.Button
        CAM4AButton        matlab.ui.control.Button
        CAM2BButton        matlab.ui.control.Button
        CAM2AButton        matlab.ui.control.Button
        CAM3Button         matlab.ui.control.Button
        CAM6Button         matlab.ui.control.Button
        CAM7Button         matlab.ui.control.Button
        CAM5Button         matlab.ui.control.Button
        CAM1CButton        matlab.ui.control.Button
        CAM1BButton        matlab.ui.control.Button
        CAM1AButton        matlab.ui.control.Button
        ShowStagePanel     matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        Label              matlab.ui.control.Label
    end

    
    properties (Access = public)
        leftdoor = false;
        leftlight = false;
        rightdoor = false;
        rightlight = false;
        value = "off";
        freddy = 1;
        bonnie = 1;
        chica = 1;
        freddyLocation = 1;
        bonnieLocation = 1;
        chicaLocation = 1;
        cameraatfreddy = false;
        camerajustlower = true;
        overheated = false; 
        cycle1 = 1;
        cycle2 = 1;
        initialMove = 1;
        AIlevelFreddy = 1;
        AIlevelBonnie = 1;
        AIlevelChica = 1;
        jumpscare = "yes";
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.AIlevelBonnie, app.AIlevelChica, app.AIlevelFreddy, app.jumpscare] = startgame();        
        end

        % Button pushed function: CAM1AButton
        function CAMC1ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Show Stage';

            room1 = imread('Show Stage Full.png');
            room1Freddy = imread('Show Stage Freddy Only.png');
            room1NoBonnie = imread('Show Stage No Bonnie.png');
            room1NoChica = imread('Show Stage No Chica.png');
            room1Empty = imread('Show Stage Empty.png');

            if app.bonnie == 1 && app.freddy == 1 && app.chica == 1
                    imshow(room1, 'Parent', app.UIAxes);
                elseif app.bonnie == 1 && app.freddy == 1 && app.chica ~= 1
                    imshow(room1NoChica, 'Parent', app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy == 1 && app.chica == 1
                    imshow (room1NoBonnie,'Parent', app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy == 1 && app.chica ~= 1
                    imshow (room1Freddy,'Parent',app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy ~= 1 && app.chica ~= 1
                    imshow (room1Empty, 'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CAM1BButton
        function CAM1BButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Dinning Hall';

            room2 = imread('Dinning Hall.png');
            room2Bonnie = imread('Dinning Hall Bonnie.png');
            room2Chica = imread('Dinning Hall Chica.png');
            room2Freddy = imread('Dinning Hall Freddy.png');

            if app.bonnie ~= 2 && app.freddy ~= 2 && app.chica ~= 2
                     imshow (room2,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy == 2 && app.chica ~= 2
                     imshow (room2Bonnie,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy ~= 2 && app.chica ~= 2
                     imshow (room2Bonnie,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy == 2 && app.chica ~= 2
                     imshow (room2Freddy,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy == 2 && app.chica == 2
                     imshow (room2Chica,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy ~= 2 && app.chica == 2
                     imshow (room2Chica,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy == 2 && app.chica == 2
                     if randi(2) == 1 
                         imshow(room2Bonnie,'Parent', app.UIAxes);
                     elseif randi (2) == 2
                         imshow (room2Chica,'Parent',app.UIAxes);
                     end
                 elseif app.bonnie == 2 && app.freddy ~= 2 && app.chica == 2
                     if randi(2) == 1 
                         imshow(room2Bonnie,'Parent', app.UIAxes);
                     elseif randi (2) == 2
                         imshow (room2Chica,'Parent',app.UIAxes);
                     end
             end
        end

        % Callback function
        function ShowStagePanelButtonDown(app, event)
        
                
        end

        % Button pushed function: CAM1CButton
        function CAM1CButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Pirate Cove';

            room3 = imread('Pirate Cove.png');
            room3Stage1 = imread('Pirate Cove Foxy Stage 1.png');
            room3Stage2 = imread('Pirate Cove Foxy Stage 2.png');
            room3Stage3 = imread('Pirate Cove Foxy Stage 3.png');
        end

        % Button pushed function: CAM2AButton
        function CAM2AButtonPushed(app, event)
            app.ShowStagePanel.Title = 'West Hall';

            room4 = imread('West Hall.png');
            room4Bonnie = imread('West Hall Bonnie.png');

            if app.bonnie == 4 
                 imshow(room4Bonnie, 'Parent', app.UIAxes);
            elseif app.bonnie ~= 4
                 imshow (room4,'Parent', app.UIAxes);
            end
        end

        % Button pushed function: CAM2BButton
        function CAM2BButtonPushed(app, event)
            app.ShowStagePanel.Title = 'West Hall Corner';

            room5 = imread('West Hall Corner.png');
            room5Bonnie = imread('West Hall Corner Bonnie.png');

            if app.bonnie == 5
                       imshow(room5Bonnie,'Parent',app.UIAxes);
                   elseif app.bonnie ~= 5
                       imshow(room5,'Parent', app.UIAxes);
            end
        end

        % Button pushed function: CAM3Button
        function CAM3ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Supply Closet';

            room6 = imread('Supply Closet.png');
            room6Bonnie = imread('Supply Closet Bonnie.png');

            if app.bonnie == 6 
                       imshow(room6Bonnie,'Parent', app.UIAxes);
                   elseif app.bonnie ~= 6
                       imshow(room6,'Parent', app.UIAxes);
            end
        end

        % Button pushed function: CAM4AButton
        function CAM4AButtonPushed(app, event)
            app.ShowStagePanel.Title = 'East Hall';

            room7 = imread('East Hall.png');
            room7Chica = imread('East Hall Chica.png');
            room7Freddy = imread('East Hall Freddy.png');

            if app.freddy ~= 7 && app.chica == 7
                       imshow(room7Chica,'Parent', app.UIAxes);
                   elseif app.freddy == 7 && app.chica ~= 7
                       imshow(room7Freddy,'Parent', app.UIAxes);
                   % If Freddy and Chica are both in this room, only Chica ppear on the camera
                   elseif app.freddy ==7 && app.chica == 7
                       imshow(room7chica,'Parent', app.UIAxes);
                   elseif app.freddy ~= 7 && app.chica ~= 7
                       imshow(room7,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CAM4BButton
        function CAM4BButtonPushed(app, event)
            app.ShowStagePanel.Title = 'East Hall Corner';

            room8 = imread('East Hall Corner .png');
            room8Chica = imread('East Hall Corner Chica.png');
            room8Freddy = imread('East Hall Corner Freddy.png');

            if app.chica ~= 8 && app.freddy ~= 8
                       imshow(room8,"Parent",app.UIAxes);
                   elseif app.chica == 8 && app.freddy ~= 8
                       imshow(room8Chica,'Parent', app.UIAxes);
                   elseif app.chica ~= 8 && app.freddy == 8
                       imshow(room8Freddy,'Parent',app.UIAxes);
                   % If Freddy and Chica are both in this room, only Chica ppear on the camera
                   elseif app.chica == 8 && app.freddy == 8
                       imshow(room8chica,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CAM5Button
        function CAM5ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Backstage';

            room9 = imread('Backstage.png');
            room9Bonnie = imread('Backstage Bonnie.png');

            if app.bonnie == 9 
                       imshow(room9Bonnie,'Parent',app.UIAxes);
                   elseif app.bonnie ~= 9
                       imshow(room9,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CAM6Button
        function CAM6ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Kitchen ( - - Video disabled. Audio only - -)';
        end

        % Button pushed function: CAM7Button
        function CAM7ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Restroom';

            room11 = imread('Restroom.png');
            room11Chica = imread('Restroom Chica.png');
            room11Freddy = imread('Restroom Freddy.png');

            if  app.chica == 11 && app.freddy ~= 11
                       imshow(room11Chica,'Parent',app.UIAxes);
                   elseif app.chica == 11 && app.freddy == 11
                       imshow(room11Chica,'Parent',app.UIAxes);
                   elseif app.chica ~= 11 && app.freddy == 11
                       imshow(room11Freddy,'Parent',app.UIAxes);
                   elseif app.chica ~= 11 && app.freddy ~= 11
                       imshow(room11,'Parent',app.UIAxes);
                   end
        end

        % Button pushed function: CameraOnButton
        function CameraOnButtonPushed(app, event)
            switch app.CameraOnButton.Text
                case 'Camera On'
                    app.CameraOnButton.Text = 'Camera Off';
                    app.CAM1AButton.Visible = 'on';
                    app.CAM1BButton.Visible = 'on';
                    app.CAM2AButton.Visible = 'on';
                    app.CAM1CButton.Visible = 'on';
                    app.CAM2BButton.Visible = 'on';
                    app.CAM3Button.Visible = 'on';
                    app.CAM4AButton.Visible = 'on';
                    app.CAM4BButton.Visible = 'on';
                    app.CAM5Button.Visible = 'on';
                    app.CAM6Button.Visible = 'on';
                    app.CAM7Button.Visible = 'on';
                    app.ShowStagePanel.Visible = 'on';
                    app.YouLabel.Visible = 'on';
                case 'Camera Off'
                    app.CameraOnButton.Text = 'Camera On';
                    app.CAM1AButton.Visible = 'off';
                    app.CAM1BButton.Visible = 'off';
                    app.CAM1CButton.Visible = 'off';
                    app.CAM2AButton.Visible = 'off';
                    app.CAM2BButton.Visible = 'off';
                    app.CAM3Button.Visible = 'off';
                    app.CAM4AButton.Visible = 'off';
                    app.CAM4BButton.Visible = 'off';
                    app.CAM5Button.Visible = 'off';
                    app.CAM6Button.Visible = 'off';
                    app.CAM7Button.Visible = 'off';
                    app.YouLabel.Visible = 'off';
                    app.ShowStagePanel.Visible = 'off';
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Load the images of the rooms to the app
           
            room1 = imread('Show Stage Full.png');
            room1Freddy = imread('Show Stage Freddy Only.png');
            room1NoBonnie = imread('Show Stage No Bonnie.png');
            room1NoChica = imread('Show Stage No Chica.png');
            room1Empty = imread('Show Stage Empty.png');

            room2 = imread('Dinning Hall.png');
            room2Bonnie = imread('Dinning Hall Bonnie.png');
            room2Chica = imread('Dinning Hall Chica.png');
            room2Freddy = imread('Dinning Hall Freddy.png');

            room3 = imread('Pirate Cove.png');
            room3Stage1 = imread('Pirate Cove Foxy Stage 1.png');
            room3Stage2 = imread('Pirate Cove Foxy Stage 2.png');
            room3Stage3 = imread('Pirate Cove Foxy Stage 3.png');

            room4 = imread('West Hall.png');
            room4Bonnie = imread('West Hall Bonnie.png');

            room5 = imread('West Hall Corner.png');
            room5Bonnie = imread('West Hall Corner Bonnie.png');

            room6 = imread('Supply Closet.png');
            room6Bonnie = imread('Supply Closet Bonnie.png');

            room7 = imread('East Hall.png');
            room7Chica = imread('East Hall Chica.png');
            room7Freddy = imread('East Hall Freddy.png');

            room8 = imread('East Hall Corner .png');
            room8Chica = imread('East Hall Corner Chica.png');
            room8Freddy = imread('East Hall Corner Freddy.png');

            room9 = imread('Backstage.png');
            room9Bonnie = imread('Backstage Bonnie.png');

            room11 = imread('Restroom.png');
            room11Chica = imread('Restroom Chica.png');
            room11Freddy = imread('Restroom Freddy.png');
            
            % Show stage camera 
            if app.ShowStagePanel.Title == "Show Stage"
                if app.bonnie == 1 && app.freddy == 1 && app.chica == 1
                    imshow(room1, 'Parent', app.UIAxes);
                elseif app.bonnie == 1 && app.freddy == 1 && app.chica ~= 1
                    imshow(room1NoChica, 'Parent', app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy == 1 && app.chica == 1
                    imshow (room1NoBonnie,'Parent', app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy == 1 && app.chica ~= 1
                    imshow (room1Freddy,'Parent',app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy ~= 1 && app.chica ~= 1
                    imshow (room1Empty, 'Parent',app.UIAxes);
                end
            end
             
             % Dinning Hall camera 
             if app.ShowStagePanel.Title == "Dinning Hall"
                 if app.bonnie ~= 2 && app.freddy ~= 2 && app.chica ~= 2
                     imshow (room2,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy == 2 && app.chica ~= 2
                     imshow (room2Bonnie,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy ~= 2 && app.chica ~= 2
                     imshow (room2Bonnie,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy == 2 && app.chica ~= 2
                     imshow (room2Freddy,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy == 2 && app.chica == 2
                     imshow (room2Chica,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy ~= 2 && app.chica == 2
                     imshow (room2Chica,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy == 2 && app.chica == 2
                     if randi(2) == 1 
                         imshow(room2bonnie,'Parent', app.UIAxes);
                     elseif randi (2) == 2
                         imshow (room2chica,'Parent',app.UIAxes);
                     end
                 elseif app.bonnie == 2 && app.freddy ~= 2 && app.chica == 2
                     if randi(2) == 1 
                         imshow(room2bonnie,'Parent', app.UIAxes);
                     elseif randi (2) == 2
                         imshow (room2chica,'Parent',app.UIAxes);
                     end
                 end
             end

               % Pirate Cove camera

               % West Hall Camera
               if app.ShowStagePanel.Title == "West Hall"
                   if app.bonnie == 4 
                       imshow(room4Bonnie, 'Parent', app.UIAxes);
                   elseif app.bonnie ~= 4
                       imshow (room4,'Parent', app.UIAxes);
                   end
               end

               % West Hall Corner camera
               if app.ShowStagePanel.Title == "West Hall Corner"
                   if app.bonnie == 5
                       imshow(room5Bonnie,'Parent',app.UIAxes);
                   elseif app.bonnie ~= 5
                       imshow(room5,'Parent', app.UIAxes);
                   end
               end

               % Supply Closet camera
               if app.ShowStagePanel.Title == "Supply Closet"
                   if app.bonnie == 6 
                       imshow(room6Bonnie,'Parent', app.UIAxes);
                   elseif app.bonnie ~= 6
                       imshow(room6,'Parent', app.UIAxes);
                   end
               end

               % East Hall camera
               if app.ShowStagePanel.Title == "East Hall"
                   if app.freddy ~= 7 && app.chica == 7
                       imshow(room7Chica,'Parent', app.UIAxes);
                   elseif app.freddy == 7 && app.chica ~= 7
                       imshow(room7Freddy,'Parent', app.UIAxes);
                   % If Freddy and Chica are both in this room, only Chica ppear on the camera
                   elseif app.freddy ==7 && app.chica == 7
                       imshow(room7chica,'Parent', app.UIAxes);
                   elseif app.freddy ~= 7 && app.chica ~= 7
                       imshow(room7,'Parent',app.UIAxes);
                   end
               end

               % East Hall Corner camera
               if app.ShowStagePanel.Title == "East Hall Corner"
                   if app.chica ~= 8 && app.freddy ~= 8
                       imshow(room8,"Parent",app.UIAxes);
                   elseif app.chica == 8 && app.freddy ~= 8
                       imshow(room8Chica,'Parent', app.UIAxes);
                   elseif app.chica ~= 8 && app.freddy == 8
                       imshow(room8Freddy,'Parent',app.UIAxes);
                   % If Freddy and Chica are both in this room, only Chica ppear on the camera
                   elseif app.chica == 8 && app.freddy == 8
                       imshow(room8chica,'Parent',app.UIAxes);
                   end
               end

               % Backstage camera
               if app.ShowStagePanel.Title == "Backstage"
                   if app.bonnie == 9 
                       imshow(room9Bonnie,'Parent',app.UIAxes);
                   elseif app.bonnie ~= 9
                       imshow(room9,'Parent',app.UIAxes);
                   end
               end
               
               % Kitchen Audio

               % Restroom
               if app.ShowStagePanel.Title == "Restroom"
                   if  app.chica == 11 && app.freddy ~= 11
                       imshow(room11chica,'Parent',app.UIAxes);
                   elseif app.chica == 11 && app.freddy == 11
                       imshow(room11Chica,'Parent',app.UIAxes);
                   elseif app.chica ~= 11 && app.freddy == 11
                       imshow(room11Freddy,'Parent',app.UIAxes);
                   elseif app.chica ~= 11 && app.freddy ~= 11
                       imshow(room11,'Parent',app.UIAxes);
                   end
               end
                        
        end

        % Value changed function: LDoorSwitch
        function LDoorSwitchValueChanged(app, event)
            app.value = app.LDoorSwitch.Value;
            % Door open if switch is off
            if app.value == "off"
                app.leftdoor = false;
            % Door closed if switch is on    
            elseif app.value == "on"
                app.leftdoor = true;
            end
            % Door no longer works if overheated
            if app.overheated == true 
                app.value = "off";
            end
        end

        % Value changed function: LLightSwitch
        function LLightSwitchValueChanged(app, event)
            app.value = app.LLightSwitch.Value;
            % Light on if switch is on
            if app.value == "on"
                app.leftlight = true;
            % Light off if switch is off 
            elseif app.value == "off"
                app.leftlight = false;
            end
            % Light no longer if overheated
            if app.overheated == true
                app.value = "off";
            end
        end

        % Value changed function: RLightSwitch
        function RLightSwitchValueChanged(app, event)
            app.value = app.RLightSwitch.Value;
            % Light on if switch is on
            if app.value == "on"
                app.rightlight = true;
            % Light off if switch is off 
            elseif app.value == "off"
                app.rightlight = false;
            end
            % Light no longer if overheated
            if app.overheated == true
                app.value = "off";
            end
        end

        % Value changed function: RDoorSwitch
        function RDoorSwitchValueChanged(app, event)
            app.value = app.RDoorSwitch.Value;
            % Door open if switch is off
            if app.value == "off"
                app.rightdoor = false;
            % Door closed if switch is on
            elseif app.value == "on"
                app.rightdoor = true;
            end
            % Door no longer works if overheated
            if app.overheated == true
                app.value = "off";
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
           
        end

        % Button down function: Panel
        function PanelButtonDown(app, event)
            for i =1:10
            [app.freddy, app.bonnie, app.chica, ...
                app.freddyLocation, app.bonnieLocation, app.chicaLocation, ...
                app.cycle1, app.cycle2, ... 
                app.initialMove] = fnafTest5 ...
                ...
                (app.leftdoor, app.rightdoor, ...
                app.freddy, app.bonnie, app.chica, ...
                app.camerajustlower, app.cameraatfreddy, ...
                app.cycle1, app.cycle2, app.initialMove, ...
                app.AIlevelFreddy, app.AIlevelBonnie, app.AIlevelChica);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.ButtonDownFcn = createCallbackFcn(app, @PanelButtonDown, true);
            app.ShowStagePanel = uipanel(app.Panel);
            app.ShowStagePanel.Title = 'Show Stage';
            app.UIAxes = uiaxes(app.ShowStagePanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CAM1AButton = uibutton(app.Panel, 'push');
            app.CAM1AButton.ButtonPushedFcn = createCallbackFcn(app, @CAMC1ButtonPushed, true);
            app.CAM1AButton.Text = 'CAM 1A';
            app.CAM1BButton = uibutton(app.Panel, 'push');
            app.CAM1BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM1BButtonPushed, true);
            app.CAM1BButton.Text = 'CAM 1B';
            app.CAM1CButton = uibutton(app.Panel, 'push');
            app.CAM1CButton.ButtonPushedFcn = createCallbackFcn(app, @CAM1CButtonPushed, true);
            app.CAM1CButton.Text = 'CAM 1C';
            app.CAM5Button = uibutton(app.Panel, 'push');
            app.CAM5Button.ButtonPushedFcn = createCallbackFcn(app, @CAM5ButtonPushed, true);
            app.CAM5Button.Text = 'CAM 5';
            app.CAM7Button = uibutton(app.Panel, 'push');
            app.CAM7Button.ButtonPushedFcn = createCallbackFcn(app, @CAM7ButtonPushed, true);
            app.CAM7Button.Text = 'CAM 7';
            app.CAM6Button = uibutton(app.Panel, 'push');
            app.CAM6Button.ButtonPushedFcn = createCallbackFcn(app, @CAM6ButtonPushed, true);
            app.CAM6Button.Text = 'CAM 6';
            app.CAM3Button = uibutton(app.Panel, 'push');
            app.CAM3Button.ButtonPushedFcn = createCallbackFcn(app, @CAM3ButtonPushed, true);
            app.CAM3Button.Text = 'CAM 3';
            app.CAM2AButton = uibutton(app.Panel, 'push');
            app.CAM2AButton.ButtonPushedFcn = createCallbackFcn(app, @CAM2AButtonPushed, true);
            app.CAM2AButton.Text = 'CAM 2A';
            app.CAM2BButton = uibutton(app.Panel, 'push');
            app.CAM2BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM2BButtonPushed, true);
            app.CAM2BButton.Text = 'CAM 2B';
            app.CAM4AButton = uibutton(app.Panel, 'push');
            app.CAM4AButton.ButtonPushedFcn = createCallbackFcn(app, @CAM4AButtonPushed, true);
            app.CAM4AButton.Text = 'CAM 4A';
            app.CAM4BButton = uibutton(app.Panel, 'push');
            app.CAM4BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM4BButtonPushed, true);
            app.CAM4BButton.Text = 'CAM 4B';
            app.YouLabel = uilabel(app.Panel);
            app.YouLabel.Text = 'You';
            app.CameraOnButton = uibutton(app.Panel, 'push');
            app.CameraOnButton.ButtonPushedFcn = createCallbackFcn(app, @CameraOnButtonPushed, true);
            app.CameraOnButton.Text = 'Camera On';
            app.LLightSwitchLabel = uilabel(app.Panel);
            app.LLightSwitchLabel.Text = 'L Light';
            app.LLightSwitch = uiswitch(app.Panel, 'slider');
            app.LLightSwitch.ValueChangedFcn = createCallbackFcn(app, @LLightSwitchValueChanged, true);
            app.RLightSwitchLabel = uilabel(app.Panel);
            app.RLightSwitchLabel.Text = 'R Light';
            app.RLightSwitch = uiswitch(app.Panel, 'slider');
            app.RLightSwitch.ValueChangedFcn = createCallbackFcn(app, @RLightSwitchValueChanged, true);
            app.LDoorSwitchLabel = uilabel(app.Panel);
            app.LDoorSwitchLabel.Text = 'L Door';
            app.LDoorSwitch = uiswitch(app.Panel, 'toggle');
            app.LDoorSwitch.ValueChangedFcn = createCallbackFcn(app, @LDoorSwitchValueChanged, true);
            app.RDoorSwitchLabel = uilabel(app.Panel);
            app.RDoorSwitchLabel.Text = 'R Door';
            app.RDoorSwitch = uiswitch(app.Panel, 'toggle');
            app.RDoorSwitch.ValueChangedFcn = createCallbackFcn(app, @RDoorSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnafv5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end