classdef fnafv6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PLabel             matlab.ui.control.Label
        OfficePanel        matlab.ui.container.Panel
        ShowStagePanel     matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        OfficeView         matlab.ui.control.UIAxes
        RDoorSwitch        matlab.ui.control.ToggleSwitch
        RDoorSwitchLabel   matlab.ui.control.Label
        LDoorSwitch        matlab.ui.control.ToggleSwitch
        LDoorSwitchLabel   matlab.ui.control.Label
        RLightSwitch       matlab.ui.control.Switch
        RLightSwitchLabel  matlab.ui.control.Label
        LLightSwitch       matlab.ui.control.Switch
        LLightSwitchLabel  matlab.ui.control.Label
        CameraOnButton     matlab.ui.control.Button
        YouLabel           matlab.ui.control.Label
        CAM4BButton        matlab.ui.control.Button
        CAM4AButton        matlab.ui.control.Button
        CAM2BButton        matlab.ui.control.Button
        CAM2AButton        matlab.ui.control.Button
        CAM3Button         matlab.ui.control.Button
        CAM6Button         matlab.ui.control.Button
        CAM7Button         matlab.ui.control.Button
        CAM5Button         matlab.ui.control.Button
        CAM1BButton        matlab.ui.control.Button
        CAM1AButton        matlab.ui.control.Button
        Label              matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Values initialization
        % Left and right doors,lights are off
        leftdoor = false;
        leftlight = false;
        rightdoor = false;
        rightlight = false;
        value = "off";
        % Freddy, Bonnie and Chica are at Show Stage at first
        freddy = 1;
        bonnie = 1;
        chica = 1;
        % Initialize animatronics location index
        freddyLocation = 1;
        bonnieLocation = 1;
        chicaLocation = 1;
        % Camera state index is on
        camera = true;
        % Door overheated value
        overheated = false; 
        % Movement randomization cycles
        cycle1 = 1; % Bonnie and Chica's cycle
        cycle2 = 1; % Freddy's cycle
        % Number of movement that have been made
        initialMove = 1;
        % Animatronics' AI level 
        AIlevelFreddy = 1;
        AIlevelBonnie = 1;
        AIlevelChica = 1;
        % Jumpscare option
        jumpscare = "yes";
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       % Run start game procedure (introduction, player name and difficulty)
            [app.AIlevelBonnie, app.AIlevelChica, app.AIlevelFreddy, ...
                app.jumpscare] = startgame();  
       % Run the main function that operate the game
             for i =1:50
            [app.freddy, app.bonnie, app.chica, ...
                app.freddyLocation, app.bonnieLocation, app.chicaLocation, ...
                app.cycle1, app.cycle2, ... 
                app.initialMove] = fnafTest6 ...
                ...
                (app.leftdoor, app.rightdoor, ...
                app.freddy, app.bonnie, app.chica, ...
                app.camera, ...
                app.cycle1, app.cycle2, app.initialMove, ...
                app.AIlevelFreddy, app.AIlevelBonnie, app.AIlevelChica);
             end
        end

        % Button pushed function: CAM1AButton
        function CAMC1ButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'Show Stage';
            
            % Read the image files as the button is pressed
            room1 = imread('Show Stage Full.png');
            room1Freddy = imread('Show Stage Freddy Only.png');
            room1NoBonnie = imread('Show Stage No Bonnie.png');
            room1NoChica = imread('Show Stage No Chica.png');
            room1Empty = imread('Show Stage Empty.png');
            
            % Show the footage accroding to the animatronics' locations
            if app.bonnie == 1 && app.freddy == 1 && app.chica == 1
                    imshow(room1, 'Parent', app.UIAxes);
                elseif app.bonnie == 1 && app.freddy == 1 && app.chica ~= 1
                    imshow(room1NoChica, 'Parent', app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy == 1 && app.chica == 1
                    imshow (room1NoBonnie,'Parent', app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy == 1 && app.chica ~= 1
                    imshow (room1Freddy,'Parent',app.UIAxes);
                elseif app.bonnie ~= 1 && app.freddy ~= 1 && app.chica ~= 1
                    imshow (room1Empty, 'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CAM1BButton
        function CAM1BButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'Dinning Hall';
            
            % Read the image files as the button is pressed
            room2 = imread('Dinning Hall.png');
            room2Bonnie = imread('Dinning Hall Bonnie.png');
            room2Chica = imread('Dinning Hall Chica.png');
            room2Freddy = imread('Dinning Hall Freddy.png');

            % Show the footage accroding to the animatronics' locations
            if app.bonnie ~= 2 && app.freddy ~= 2 && app.chica ~= 2
                     imshow (room2,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy == 2 && app.chica ~= 2
                     imshow (room2Bonnie,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy ~= 2 && app.chica ~= 2
                     imshow (room2Bonnie,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy == 2 && app.chica ~= 2
                     imshow (room2Freddy,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy == 2 && app.chica == 2
                     imshow (room2Chica,'Parent', app.UIAxes);
                 elseif app.bonnie ~= 2 && app.freddy ~= 2 && app.chica == 2
                     imshow (room2Chica,'Parent', app.UIAxes);
                 elseif app.bonnie == 2 && app.freddy == 2 && app.chica == 2
                     if randi(2) == 1 
                         imshow(room2Bonnie,'Parent', app.UIAxes);
                     elseif randi (2) == 2
                         imshow (room2Chica,'Parent',app.UIAxes);
                     end
                 elseif app.bonnie == 2 && app.freddy ~= 2 && app.chica == 2
                     if randi(2) == 1 
                         imshow(room2Bonnie,'Parent', app.UIAxes);
                     elseif randi (2) == 2
                         imshow (room2Chica,'Parent',app.UIAxes);
                     end
             end
        end

        % Callback function
        function ShowStagePanelButtonDown(app, event)
        
                
        end

        % Button pushed function: CAM2AButton
        function CAM2AButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'West Hall';

            % Read the image files as the button is pressed
            room4 = imread('West Hall.png');
            room4Bonnie = imread('West Hall Bonnie.png');

            % Show the footage accroding to the animatronics' locations
            if app.bonnie == 4 
                 imshow(room4Bonnie, 'Parent', app.UIAxes);
            elseif app.bonnie ~= 4
                 imshow (room4,'Parent', app.UIAxes);
            end
        end

        % Button pushed function: CAM2BButton
        function CAM2BButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'West Hall Corner';

            % Read the image files as the button is pressed
            room5 = imread('West Hall Corner.png');
            room5Bonnie = imread('West Hall Corner Bonnie.png');

            % Show the footage accroding to the animatronics' locations
            if app.bonnie == 5
                       imshow(room5Bonnie,'Parent',app.UIAxes);
                   elseif app.bonnie ~= 5
                       imshow(room5,'Parent', app.UIAxes);
            end
        end

        % Button pushed function: CAM3Button
        function CAM3ButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'Supply Closet';

            % Read the image files as the button is pressed
            room6 = imread('Supply Closet.png');
            room6Bonnie = imread('Supply Closet Bonnie.png');

            % Show the footage accroding to the animatronics' locations
            if app.bonnie == 6 
                       imshow(room6Bonnie,'Parent', app.UIAxes);
                   elseif app.bonnie ~= 6
                       imshow(room6,'Parent', app.UIAxes);
            end
        end

        % Button pushed function: CAM4AButton
        function CAM4AButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'East Hall';

            % Read the image files as the button is pressed
            room7 = imread('East Hall.png');
            room7Chica = imread('East Hall Chica.png');
            room7Freddy = imread('East Hall Freddy.png');

            % Show the footage accroding to the animatronics' locations
            if app.freddy ~= 7 && app.chica == 7
                       imshow(room7Chica,'Parent', app.UIAxes);
                   elseif app.freddy == 7 && app.chica ~= 7
                       imshow(room7Freddy,'Parent', app.UIAxes);
                   % If Freddy and Chica are both in this room, only Chica ppear on the camera
                   elseif app.freddy ==7 && app.chica == 7
                       imshow(room7chica,'Parent', app.UIAxes);
                   elseif app.freddy ~= 7 && app.chica ~= 7
                       imshow(room7,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CAM4BButton
        function CAM4BButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'East Hall Corner';

            % Read the image files as the button is pressed
            room8 = imread('East Hall Corner .png');
            room8Chica = imread('East Hall Corner Chica.png');
            room8Freddy = imread('East Hall Corner Freddy.png');

            % Show the footage accroding to the animatronics' locations
            if app.chica ~= 8 && app.freddy ~= 8
                       imshow(room8,"Parent",app.UIAxes);
                   elseif app.chica == 8 && app.freddy ~= 8
                       imshow(room8Chica,'Parent', app.UIAxes);
                   elseif app.chica ~= 8 && app.freddy == 8
                       imshow(room8Freddy,'Parent',app.UIAxes);
                   % If Freddy and Chica are both in this room, only Chica ppear on the camera
                   elseif app.chica == 8 && app.freddy == 8
                       imshow(room8chica,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CAM5Button
        function CAM5ButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'Backstage';

            % Read the image files as the button is pressed
            room9 = imread('Backstage.png');
            room9Bonnie = imread('Backstage Bonnie.png');

            % Show the footage accroding to the animatronics' locations
            if app.bonnie == 9 
                       imshow(room9Bonnie,'Parent',app.UIAxes);
                   elseif app.bonnie ~= 9
                       imshow(room9,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CAM6Button
        function CAM6ButtonPushed(app, event)
            app.ShowStagePanel.Title = 'Kitchen ( - - Video disabled. Audio only - -)';
            room10chica = audioread('kitchen.mp3');

            % Show blank, dark image as there's no video signal for this
            % camera
            room10 = imread('Kitchen.png');
            imshow(room10,'Parent',app.UIAxes);

            % Play sound when Chica is in the kitchen
            if app.chica == 10
                chicasound = audioplayer(room10chica, 16000);
                play(chicasound);
            end
        end

        % Button pushed function: CAM7Button
        function CAM7ButtonPushed(app, event)
            % Camera name changes accroding to the camera button
            app.ShowStagePanel.Title = 'Restroom';

            % Read the image files as the button is pressed
            room11 = imread('Restroom.png');
            room11Chica = imread('Restroom Chica.png');
            room11Freddy = imread('Restroom Freddy.png');

            % Show the footage accroding to the animatronics' locations
            if  app.chica == 11 && app.freddy ~= 11
                       imshow(room11Chica,'Parent',app.UIAxes);
                   elseif app.chica == 11 && app.freddy == 11
                       imshow(room11Chica,'Parent',app.UIAxes);
                   elseif app.chica ~= 11 && app.freddy == 11
                       imshow(room11Freddy,'Parent',app.UIAxes);
                   elseif app.chica ~= 11 && app.freddy ~= 11
                       imshow(room11,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CameraOnButton
        function CameraOnButtonPushed(app, event)
            switch app.CameraOnButton.Text
                case 'Camera On'
                    % Show the camera buttons and camera view as the  camera switch is on
                    app.CameraOnButton.Text = 'Camera Off';
                    app.CAM1AButton.Visible = 'on';
                    app.CAM1BButton.Visible = 'on';
                    app.CAM2AButton.Visible = 'on'; 
                    app.CAM2BButton.Visible = 'on';
                    app.CAM3Button.Visible = 'on';
                    app.CAM4AButton.Visible = 'on';
                    app.CAM4BButton.Visible = 'on';
                    app.CAM5Button.Visible = 'on';
                    app.CAM6Button.Visible = 'on';
                    app.CAM7Button.Visible = 'on';
                    app.ShowStagePanel.Visible = 'on';
                    app.YouLabel.Visible = 'on';
                    app.camera = true;
                case 'Camera Off'
                    % Hide the camera buttons and camera view as the camera switch is off
                    app.CameraOnButton.Text = 'Camera On';
                    app.CAM1AButton.Visible = 'off';
                    app.CAM1BButton.Visible = 'off';                 
                    app.CAM2AButton.Visible = 'off';
                    app.CAM2BButton.Visible = 'off';
                    app.CAM3Button.Visible = 'off';
                    app.CAM4AButton.Visible = 'off';
                    app.CAM4BButton.Visible = 'off';
                    app.CAM5Button.Visible = 'off';
                    app.CAM6Button.Visible = 'off';
                    app.CAM7Button.Visible = 'off';
                    app.YouLabel.Visible = 'off';
                    app.ShowStagePanel.Visible = 'off';
                    app.camera = false;
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Value changed function: LDoorSwitch
        function LDoorSwitchValueChanged(app, event)
            app.value = app.LDoorSwitch.Value;
            % Door open if switch is off
            if app.value == "off"
                app.leftdoor = false;
            % Door closed if switch is on    
            elseif app.value == "on"
                app.leftdoor = true;
            end
            % Door no longer works if overheated
            if app.overheated == true 
                app.value = "off";
            end

            % Animatronic at door way view
            room12bonnie = imread('Office Bonnie Door Open .png');
            room12chica = imread('Office Chica Door Open.png');

            % Office view 
            room13 = imread('Office.png');
            % Light view when there're no animatronics
            room13rightlight = imread('Office Right Light.png');
            room13leftlight = imread('Office Left Light.png');
            % Office view when door is closed
            room13rightdoor = imread('Office Right Door Light Off.jpg');
            room13rightdoorLight = imread('Office Right Door Light On.jpg');
            room13leftdoor = imread('Office Left Door Light Off.jpg');
            room13leftdoorLight = imread('Office Left Door Light On.jpg');

            % Decide what the user will see from the office based on their
            % actions (light and door switches, animatronics)
            if app.CameraOnButton.Text == "Camera On"
                if app.bonnie == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == true && app.rightlight == false
                        imshow(room12bonnie,'Parent',app.OfficeView);

                elseif app.chica == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == false && app.rightlight == true
                        imshow(room12chica,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == right ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13rightlight,'Parent',app.OfficeView);

                elseif app.leftlight == true && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13leftlight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoor,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == true ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoorLight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoor,'Parent',app.OfficeView);

                elseif app.leftlight == right && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoorLight,'Parent',app.OfficeView);
                end
            end
        end

        % Value changed function: LLightSwitch
        function LLightSwitchValueChanged(app, event)
            app.value = app.LLightSwitch.Value;
            % Light on if switch is on
            if app.value == "on"
                app.leftlight = true;
            % Light off if switch is off 
            elseif app.value == "off"
                app.leftlight = false;
            end
            % Light no longer if overheated
            if app.overheated == true
                app.value = "off";
            end

            % Animatronic at door way view
            room12bonnie = imread('Office Bonnie Door Open .png');
            room12chica = imread('Office Chica Door Open.png');

            % Office view 
            room13 = imread('Office.png');
            % Light view when there're no animatronics
            room13rightlight = imread('Office Right Light.png');
            room13leftlight = imread('Office Left Light.png');
            % Office view when door is closed
            room13rightdoor = imread('Office Right Door Light Off.jpg');
            room13rightdoorLight = imread('Office Right Door Light On.jpg');
            room13leftdoor = imread('Office Left Door Light Off.jpg');
            room13leftdoorLight = imread('Office Left Door Light On.jpg');

            % Decide what the user will see from the office based on their
            % actions (light and door switches, animatronics)
            if app.CameraOnButton.Text == "Camera On"
                if app.bonnie == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == true && app.rightlight == false
                        imshow(room12bonnie,'Parent',app.OfficeView);

                elseif app.chica == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == false && app.rightlight == true
                        imshow(room12chica,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == right ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13rightlight,'Parent',app.OfficeView);

                elseif app.leftlight == true && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13leftlight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoor,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == true ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoorLight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoor,'Parent',app.OfficeView);

                elseif app.leftlight == right && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoorLight,'Parent',app.OfficeView);
                end
            end
        end

        % Value changed function: RLightSwitch
        function RLightSwitchValueChanged(app, event)
            app.value = app.RLightSwitch.Value;
            % Light on if switch is on
            if app.value == "on"
                app.rightlight = true;
            % Light off if switch is off 
            elseif app.value == "off"
                app.rightlight = false;
            end
            % Light no longer if overheated
            if app.overheated == true
                app.value = "off";
            end

            % Animatronic at door way view
            room12bonnie = imread('Office Bonnie Door Open .png');
            room12chica = imread('Office Chica Door Open.png');

            % Office view 
            room13 = imread('Office.png');
            % Light view when there're no animatronics
            room13rightlight = imread('Office Right Light.png');
            room13leftlight = imread('Office Left Light.png');
            % Office view when door is closed
            room13rightdoor = imread('Office Right Door Light Off.jpg');
            room13rightdoorLight = imread('Office Right Door Light On.jpg');
            room13leftdoor = imread('Office Left Door Light Off.jpg');
            room13leftdoorLight = imread('Office Left Door Light On.jpg');

            % Decide what the user will see from the office based on their
            % actions (light and door switches, animatronics)
            if app.CameraOnButton.Text == "Camera On"
                if app.bonnie == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == true && app.rightlight == false
                        imshow(room12bonnie,'Parent',app.OfficeView);

                elseif app.chica == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == false && app.rightlight == true
                        imshow(room12chica,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == right ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13rightlight,'Parent',app.OfficeView);

                elseif app.leftlight == true && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13leftlight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoor,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == true ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoorLight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoor,'Parent',app.OfficeView);

                elseif app.leftlight == right && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoorLight,'Parent',app.OfficeView);
                end
            end
        end

        % Value changed function: RDoorSwitch
        function RDoorSwitchValueChanged(app, event)
            app.value = app.RDoorSwitch.Value;


            % Door open if switch is off
            if app.value == "off"
                app.rightdoor = false;
            % Door closed if switch is on
            elseif app.value == "on"
                app.rightdoor = true;
            end
            % Door no longer works if overheated
            if app.overheated == true
                app.value = "off";
            end

            % Animatronic at door way view
            room12bonnie = imread('Office Bonnie Door Open .png');
            room12chica = imread('Office Chica Door Open.png');

            % Office view 
            room13 = imread('Office.png');
            % Light view when there're no animatronics
            room13rightlight = imread('Office Right Light.png');
            room13leftlight = imread('Office Left Light.png');
            % Office view when door is closed
            room13rightdoor = imread('Office Right Door Light Off.jpg');
            room13rightdoorLight = imread('Office Right Door Light On.jpg');
            room13leftdoor = imread('Office Left Door Light Off.jpg');
            room13leftdoorLight = imread('Office Left Door Light On.jpg');

            % Decide what the user will see from the office based on their
            % actions (light and door switches, animatronics)
           if app.CameraOnButton.Text == "Camera On"
                if app.bonnie == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == true && app.rightlight == false
                        imshow(room12bonnie,'Parent',app.OfficeView);

                elseif app.chica == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == false && app.rightlight == true
                        imshow(room12chica,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == right ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13rightlight,'Parent',app.OfficeView);

                elseif app.leftlight == true && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13leftlight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoor,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == true ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoorLight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoor,'Parent',app.OfficeView);

                elseif app.leftlight == right && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoorLight,'Parent',app.OfficeView);
                end
           end
        end

        % Button down function: OfficeView
        function OfficeViewButtonDown(app, event)
            % Animatronic at door way view
            room12bonnie = imread('Office Bonnie Door Open .png');
            room12chica = imread('Office Chica Door Open.png');

            % Office view 
            room13 = imread('Office.png');
            % Light view when there're no animatronics
            room13rightlight = imread('Office Right Light.png');
            room13leftlight = imread('Office Left Light.png');
            % Office view when door is closed
            room13rightdoor = imread('Office Right Door Light Off.jpg');
            room13rightdoorLight = imread('Office Right Door Light On.jpg');
            room13leftdoor = imread('Office Left Door Light Off.jpg');
            room13leftdoorLight = imread('Office Left Door Light On.jpg');

            % Decide what the user will see from the office based on their
            % actions (light and door switches, animatronics)
           if app.CameraOnButton.Text == "Camera On"
                if app.bonnie == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == true && app.rightlight == false
                        imshow(room12bonnie,'Parent',app.OfficeView);

                elseif app.chica == 12 && ...
                    app.leftdoor == false && app.rightdoor == false && ...
                    app.leftlight == false && app.rightlight == true
                        imshow(room12chica,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == right ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13rightlight,'Parent',app.OfficeView);

                elseif app.leftlight == true && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == false
                        imshow(room13leftlight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoor,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == true ...
                    && app.rightdoor == true && app.leftdoor == false
                        imshow(room13rightdoorLight,'Parent',app.OfficeView);

                elseif app.leftlight == false && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoor,'Parent',app.OfficeView);

                elseif app.leftlight == right && app.rightlight == false ...
                    && app.rightdoor == false && app.leftdoor == true
                        imshow(room13leftdoorLight,'Parent',app.OfficeView);
                end
           end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CAM1AButton = uibutton(app.UIFigure, 'push');
            app.CAM1AButton.ButtonPushedFcn = createCallbackFcn(app, @CAMC1ButtonPushed, true);
            app.CAM1AButton.Text = 'CAM 1A';
            app.CAM1BButton = uibutton(app.UIFigure, 'push');
            app.CAM1BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM1BButtonPushed, true);
            app.CAM1BButton.Text = 'CAM 1B';
            app.CAM5Button = uibutton(app.UIFigure, 'push');
            app.CAM5Button.ButtonPushedFcn = createCallbackFcn(app, @CAM5ButtonPushed, true);
            app.CAM5Button.Text = 'CAM 5';
            app.CAM7Button = uibutton(app.UIFigure, 'push');
            app.CAM7Button.ButtonPushedFcn = createCallbackFcn(app, @CAM7ButtonPushed, true);
            app.CAM7Button.Text = 'CAM 7';
            app.CAM6Button = uibutton(app.UIFigure, 'push');
            app.CAM6Button.ButtonPushedFcn = createCallbackFcn(app, @CAM6ButtonPushed, true);
            app.CAM6Button.Text = 'CAM 6';
            app.CAM3Button = uibutton(app.UIFigure, 'push');
            app.CAM3Button.ButtonPushedFcn = createCallbackFcn(app, @CAM3ButtonPushed, true);
            app.CAM3Button.Text = 'CAM 3';
            app.CAM2AButton = uibutton(app.UIFigure, 'push');
            app.CAM2AButton.ButtonPushedFcn = createCallbackFcn(app, @CAM2AButtonPushed, true);
            app.CAM2AButton.Text = 'CAM 2A';
            app.CAM2BButton = uibutton(app.UIFigure, 'push');
            app.CAM2BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM2BButtonPushed, true);
            app.CAM2BButton.Text = 'CAM 2B';
            app.CAM4AButton = uibutton(app.UIFigure, 'push');
            app.CAM4AButton.ButtonPushedFcn = createCallbackFcn(app, @CAM4AButtonPushed, true);
            app.CAM4AButton.Text = 'CAM 4A';
            app.CAM4BButton = uibutton(app.UIFigure, 'push');
            app.CAM4BButton.ButtonPushedFcn = createCallbackFcn(app, @CAM4BButtonPushed, true);
            app.CAM4BButton.Text = 'CAM 4B';
            app.YouLabel = uilabel(app.UIFigure);
            app.YouLabel.Text = 'You';
            app.CameraOnButton = uibutton(app.UIFigure, 'push');
            app.CameraOnButton.ButtonPushedFcn = createCallbackFcn(app, @CameraOnButtonPushed, true);
            app.CameraOnButton.Text = 'Camera On';
            app.LLightSwitchLabel = uilabel(app.UIFigure);
            app.LLightSwitchLabel.Text = 'L Light';
            app.LLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.LLightSwitch.ValueChangedFcn = createCallbackFcn(app, @LLightSwitchValueChanged, true);
            app.RLightSwitchLabel = uilabel(app.UIFigure);
            app.RLightSwitchLabel.Text = 'R Light';
            app.RLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.RLightSwitch.ValueChangedFcn = createCallbackFcn(app, @RLightSwitchValueChanged, true);
            app.LDoorSwitchLabel = uilabel(app.UIFigure);
            app.LDoorSwitchLabel.Text = 'L Door';
            app.LDoorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.LDoorSwitch.ValueChangedFcn = createCallbackFcn(app, @LDoorSwitchValueChanged, true);
            app.RDoorSwitchLabel = uilabel(app.UIFigure);
            app.RDoorSwitchLabel.Text = 'R Door';
            app.RDoorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RDoorSwitch.ValueChangedFcn = createCallbackFcn(app, @RDoorSwitchValueChanged, true);
            app.OfficePanel = uipanel(app.UIFigure);
            app.OfficePanel.Title = 'Office';
            app.OfficeView = uiaxes(app.OfficePanel);
            app.OfficeView.XTick = [];
            app.OfficeView.XTickLabel = '';
            app.OfficeView.YTick = [];
            app.OfficeView.YTickLabel = '';
            app.OfficeView.ZTick = [];
            app.OfficeView.ButtonDownFcn = createCallbackFcn(app, @OfficeViewButtonDown, true);
            app.ShowStagePanel = uipanel(app.OfficePanel);
            app.ShowStagePanel.Title = 'Show Stage';
            app.UIAxes = uiaxes(app.ShowStagePanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'Please enter your name and choose difficulty in Command Window before playing ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnafv6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end